classdef M3AnalysisTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        TESTButton                  matlab.ui.control.Button
        ClearTableButton            matlab.ui.control.Button
        AddRowButton                matlab.ui.control.Button
        TimeScoreTable              matlab.ui.control.Table
        KnownScoreEditField         matlab.ui.control.NumericEditField
        KnownScoreEditFieldLabel    matlab.ui.control.Label
        KnownTimeEditField          matlab.ui.control.NumericEditField
        KnownTimeEditFieldLabel     matlab.ui.control.Label
        KnownLengthEditField        matlab.ui.control.NumericEditField
        KnownLengthEditFieldLabel   matlab.ui.control.Label
        M3AnalysisToolLabel         matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        LoadButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        PlacementText               matlab.ui.control.Label
        PlacementLabel              matlab.ui.control.Label
        CalculatePlacementButton    matlab.ui.control.Button
        TargetLengthEditField       matlab.ui.control.NumericEditField
        TargetLengthEditFieldLabel  matlab.ui.control.Label
        TargetTimeEditField         matlab.ui.control.NumericEditField
        TargetTimeEditFieldLabel    matlab.ui.control.Label
        TargetPlacementEditField    matlab.ui.control.NumericEditField
        TargetPlacementLabel        matlab.ui.control.Label
        CalculateTargetsButton      matlab.ui.control.Button
        WarningMsg                  matlab.ui.control.Label
        TimeText                    matlab.ui.control.Label
        LengthText                  matlab.ui.control.Label
        TimeLabel                   matlab.ui.control.Label
        LengthLabel                 matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    methods (Access = private)

        function inverseList = inverse(~, list)
        % Turns a list of numbers into a list of their inverses
            inverseList = zeros(1, length(list));
            for i = 1:length(list)
                inverseList(i) = 1 / list(i);
            end
        end
        
        function maxList = maxPair(~, cellArr)
        % Returns the pair with the maximum ratio
            maxList = cellArr{1};
            maxRatio = maxList(1) / maxList(2);
            for i = 1:length(cellArr)
                tempList = cellArr{i};
                tempRatio = tempList(1) / tempList(2);
                if tempRatio > maxRatio
                    maxList = tempList;
                    maxRatio = tempRatio;
                end
            end
        end

        function cellArr = rmvElement(~, cellArr, element)
        % Returns a cell array with the specified element removed
            index = find(cellfun(@(e) isequal(e, element), cellArr));
            if ~isempty(index)
                cellArr(index) = [];
            end
        end

        function numList = strToNums(~, str)
        % Turns a comma-seperated string of numbers into a list of doubles
            numStrs = split(str, ",");
            numList = str2double(numStrs);
        end
        
        function pairList = pair(~, list1, list2)
        %   Turns 2 lists into 1 list of index pairs
        %   e.g. pair([1, 2, 3], [4, 5, 6]) = {[1, 4], [2, 5], [3, 6]}
            if ~isvector(list1) || ~isvector(list2) || numel(list1) ~= numel(list2)
                error("Inputs must be vectors of the same length");
            end
            
            pairList = cell(1, length(list1));
            for i = 1:length(list1)
                pairList{i} = [list1(i), list2(i)];
            end
        end
        
        function [list1, list2] = unpair(~, pairList)
        % Turns a list of index pairs into 2 separate lists
        % e.g. unpair({[1, 4], [2, 5], [3, 6]}) = {[1, 2, 3], [4, 5, 6]}
            n = length(pairList);
            list1 = zeros(1, n);
            list2 = zeros(1, n);
            for i = 1:n
                pair = pairList{i};
                list1(i) = pair(1);
                list2(i) = pair(2);
            end
        end

        function inferredList = inferLengths(~, timeScoreList, knownCombo)
        %   Returns a list of inferred antenna lengths
        %   Input should be a list of time-score pairs and a known length-time-score combo
            if ~isvector(knownCombo) || numel(knownCombo) ~= 3
                error("Invalid known variable(s)")
            end
        
            knownTime = knownCombo(1);
            knownLength = knownCombo(2);
            knownScore = knownCombo(3);
        
            inferredList = zeros(1, length(timeScoreList));
            for i = 1:length(timeScoreList)
                tempPair = timeScoreList{i};
                tempTime = tempPair(1);
                tempScore = tempPair(2);
                tempLength = (tempScore - 2) / (knownScore - 2) * (knownLength / knownTime) * tempTime;
                inferredList(i) = tempLength;
            end
        end      

        function placement = calcPlacement(~, lengthTimeList, lengthTimePair)
        % Calculates the placement of a length-time pair in a list of lenth-time pairs
        % Do NOT include own length and time in the list
            teamsBeat = 0;
            lengthValue = lengthTimePair(1);
            timeValue = lengthTimePair(2);
            for i = 1:length(lengthTimeList)
                lengthTime = lengthTimeList{i};
                if lengthValue / timeValue >= lengthTime(1) / lengthTime(2)
                    teamsBeat = teamsBeat + 1;
                end
            end
            placement = length(lengthTimeList) - teamsBeat + 1;
        end

        function sortedList = sortRatios(~, lengthTimeList)
        % Returns a sorted list of length-time pairs, based on their ratios
            sortedList = [lengthTimeList(1)];
            for i = 1:length(lengthTimeList)
                outerLengthTime = lengthTimeList{i};
                outerRatio = outerLengthTime(1) / outerLengthTime(2);
                for j = 1:length(sortedList)
                    innerLengthTime = sortedList{j};
                    innerRatio = innerLengthTime(1) / innerLengthTime(2);
                    if outerRatio < innerRatio
                        sortedList = [lengthTimeList(i), sortedList];
                        break
                    elseif outerRatio >= innerRatio
                        sortedList = [sortedList, lengthTimeList(i)];
                        break
                    end
                end
            end
        end

        function [xInt, yInt] = lineIntersection(~, m1, b1, m2, b2)
            % Returns the intercept of 2 lines, or None if they do not
            % intercept
            xInt = (b2 - b1) / (m1 - m2);
            yInt = m1 * xInt + b1;
        end

        function updatePlot(app)
            if app.KnownTimeEditField.Value <= 0 || app.KnownLengthEditField.Value <= 0 || app.KnownScoreEditField.Value < 2 ...
               || isempty(app.TimeScoreTable.Data)
                app.WarningMsg.Text = "Error: Not all values set";
                return
            else
                app.WarningMsg.Text = "";
            end

            knownCombo = [app.KnownTimeEditField.Value, app.KnownLengthEditField.Value, app.KnownScoreEditField.Value];
            knownTime = knownCombo(1);
            knownLength = knownCombo(2);

            % The times list is read is as a column vector for some reason,
            % so we transpose it
            times = strToNums(app, strjoin(string(app.TimeScoreTable.Data(:, 1)), ","))';
            scores = strToNums(app, strjoin(string(app.TimeScoreTable.Data(:, 2)), ","));
            timeScoreList = pair(app, times, scores);
            lengths = inferLengths(app, timeScoreList, knownCombo);
        
            bestPair = maxPair(app, pair(app, lengths, times));
            bestTime = bestPair(2);
            bestLength = bestPair(1);
            bestRatio = bestLength / bestTime;

            lengths = inferLengths(app, timeScoreList, knownCombo);

            % Plot all performances
            plot(app.UIAxes, times, lengths, "o", "MarkerSize", 7, "MarkerFaceColor", "black");

            % Re-plot our own performance
            hold(app.UIAxes, "on");
            plot(app.UIAxes, knownTime, knownLength, "o", "MarkerSize", 8, "MarkerFaceColor", "blue");

            % Re-plot the best performance
            hold(app.UIAxes, "on");
            plot(app.UIAxes, bestTime, bestLength, "o", "MarkerSize", 8, "MarkerFaceColor", "green");

            % Set plot boundaries
            targetTime = app.TargetTimeEditField.Value;
            targetLength = app.TargetLengthEditField.Value;
            targetPlacement = app.TargetPlacementEditField.Value;
            xMin = min([[times, targetTime], knownTime]) * 0.80;
            xMax = max([[times, targetTime], knownTime]) * 1.20;
            xlim(app.UIAxes, [xMin, xMax]);
            yMin = min([[lengths, targetLength], knownLength]) * 0.80;
            yMax = max([[lengths, targetLength], knownLength]) * 1.20;
            ylim(app.UIAxes, [yMin, yMax]);

            if (app.TargetPlacementEditField.Value == 0 && app.TargetLengthEditField.Value == 0 && app.TargetTimeEditField.Value == 0)
                app.TargetLengthEditField.Value = knownLength;
                app.TargetTimeEditField.Value = knownTime;
            end

            % Plot slopes
            lengthTimeList = pair(app, [lengths, knownLength], [times, knownTime]);
            for i = 1:length(lengthTimeList)
                point = lengthTimeList{i};
                slope = point(1) / point(2);
                targetTime = point(2);
                targetLength = point(1);
                x = linspace(xMin, xMax);
                y = slope * (x - targetTime) + targetLength;

                if (targetTime == knownTime && targetLength == knownLength)
                    % Estimated zero-length time is 60 seconds
                    % CHANGE IF WE GET A MORE ACCURATE TIME
                    zeroLengthTime = 60;
                    point2 = [0, zeroLengthTime];
                    slope = (point(1) - point2(1)) / (point(2) - point2(2));
                    x = linspace(0, xMax);
                    y = slope * (x - targetTime) + targetLength;
                    hold(app.UIAxes, "on");
                    plot(app.UIAxes, x, y, "Color", "blue")

                    targetTime = app.TargetTimeEditField.Value;
                    targetLength = app.TargetLengthEditField.Value;

                    % Plot target performance point and slope
                    if (targetLength ~= 0 && targetTime ~= 0)
                        % Plot target based on input parameters
                        point = [targetLength, targetTime];
                        targetTime = point(2);
                        targetLength = point(1);

                        hold(app.UIAxes, "on");
                        plot(app.UIAxes, point(2), point(1), "o", "MarkerSize", 8, "MarkerFaceColor", "red");
                        
                        slope = (point(1) - point2(1)) / (point(2) - point2(2));
                        x = linspace(xMin, xMax);
                        y = slope * (x - targetTime) + targetLength;
                        hold(app.UIAxes, "on");
                        plot(app.UIAxes, x, y, "Color", "red");
                        
                        placementList = rmvElement(app, lengthTimeList, [knownLength, knownTime]);
                        placement = calcPlacement(app, placementList, point);
                        app.PlacementText.Text = string(placement);
                        app.LengthText.Text = string(targetLength);
                        app.TimeText.Text = string(targetTime);
                    elseif (targetPlacement ~= 0 && targetPlacement <= length(lengthTimeList))
                        % Plot target based on desired placement
                        newLengthTimeList = rmvElement(app, lengthTimeList, [knownLength, knownTime]);
                        sortedList = sortRatios(app, newLengthTimeList);
                        targetPair = sortedList(targetPlacement);
                        targetRatio = targetPair{1}(1) / targetPair{1}(2);
                        knownRatio = (knownLength - 0) / (knownTime - zeroLengthTime);
                        knownYInt = 0 - ((knownLength - 0) / (knownTime - zeroLengthTime)) * zeroLengthTime;
                        [targetTime, targetLength] = lineIntersection(app, targetRatio, 0, knownRatio, knownYInt);
                        if ~isnan(targetTime) && ~isnan(targetLength) && targetTime > 0 && targetLength > 0 && ~isinf(targetTime) && ~isinf(targetLength)
                            hold(app.UIAxes, "on");
                            plot(app.UIAxes, targetTime, targetLength, "o", "MarkerSize", 8, "MarkerFaceColor", "red");
                            x = linspace(xMin, xMax);
                            y = knownRatio * x + knownYInt;
                            hold(app.UIAxes, "on");
                            plot(app.UIAxes, x, y, "Color", "red");
                            
                            % Max length for our plane is 35 inches and max
                            % time is 300 seconds for M3
                            if (targetLength > 35 || targetTime > 300)
                                app.WarningMsg.Text = "Error: Placement is not possible";
                                app.PlacementText.Text = "N/A";
                                app.LengthText.Text = "N/A";
                                app.TimeText.Text = "N/A";      
                            else
                                targetLengthStr = string(round(targetLength, 2));
                                targetTimeStr = string(round(targetTime, 2));
                                app.PlacementText.Text = string(app.TargetPlacementEditField.Value);
                                app.LengthText.Text = targetLengthStr;
                                app.TimeText.Text = targetTimeStr;
                            end
                        elseif knownRatio == targetRatio
                            app.PlacementText.Text = string(targetPlacement);
                            app.LengthText.Text = string(knownLength);
                            app.TimeText.Text = string(knownTime);
                        else
                            app.WarningMsg.Text = "Error: Placement is not possible";
                            app.PlacementText.Text = "N/A";
                            app.LengthText.Text = "N/A";
                            app.TimeText.Text = "N/A";                            
                        end
                    else
                        app.PlacementText.Text = "N/A";
                        app.LengthText.Text = "N/A";
                        app.TimeText.Text = "N/A";
                    end
                elseif (targetTime == bestTime && targetLength == bestLength)
                    hold(app.UIAxes, "on");
                    plot(app.UIAxes, x, y, "Color", "green")
                else
                    hold(app.UIAxes, "on");
                    plot(app.UIAxes, x, y, "Color", "black")
                end
            end
            hold(app.UIAxes, "off");
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function updatedTimes(app, event)
            updatePlot(app);
        end

        % Callback function
        function updatedScores(app, event)
            updatePlot(app);
        end

        % Value changed function: KnownTimeEditField
        function updatedKnownTime(app, event)
            updatePlot(app);
        end

        % Value changed function: KnownLengthEditField
        function updatedKnownLength(app, event)
            updatePlot(app);
        end

        % Value changed function: KnownScoreEditField
        function updatedKnownScore(app, event)
            updatePlot(app);
        end

        % Button pushed function: AddRowButton
        function addRow(app, event)
            app.TimeScoreTable.Data = [app.TimeScoreTable.Data; [0, 0]];
            updatePlot(app);
        end

        % Cell edit callback: TimeScoreTable
        function updatedTable(app, event)
            updatePlot(app);
        end

        % Button pushed function: ClearTableButton
        function clearTable(app, event)
            app.TimeScoreTable.Data = [];
            plot(app.UIAxes, 0, 0);
            app.TargetPlacementEditField.Value = 0;
            app.TargetLengthEditField.Value = 0;
            app.TargetTimeEditField.Value = 0;
            app.PlacementText.Text = "N/A";
            app.LengthText.Text = "N/A";
            app.TimeText.Text = "N/A";        
        end

        % Button pushed function: TESTButton
        function testFunction(app, event)
            times = [90, 120, 111, 132, 107, 98, 89, 96, 105];
            scores = [2.71, 2.88, 2.99, 2.74, 2.36, 2.78, 3, 2.97, 2.93];
            app.TimeScoreTable.Data = [times', scores'];
            app.KnownTimeEditField.Value = 132;
            app.KnownLengthEditField.Value = 23;
            app.KnownScoreEditField.Value = 2.74;
            updatePlot(app);
        end

        % Button pushed function: CalculateTargetsButton
        function calculateTargets(app, event)
            app.TargetLengthEditField.Value = 0;
            app.TargetTimeEditField.Value = 0;
            updatePlot(app);
        end

        % Button pushed function: CalculatePlacementButton
        function calculatePlacement(app, event)
            app.TargetPlacementEditField.Value = 0;
            updatePlot(app);
        end

        % Button pushed function: SaveButton
        function save(app, event)
        [fileName, filePath] = uiputfile("*.csv", "Save Data As");
        if fileName ~= 0
            knownTime = app.KnownTimeEditField.Value;
            knownLength = app.KnownLengthEditField.Value;
            knownScore = app.KnownScoreEditField.Value;

            fileID = fopen(fullfile(filePath, fileName), "w");
            fprintf(fileID, "%s,%s,%s", "Known Time", "Known Length", "Known Score");
            fclose(fileID);

            writematrix([knownTime, knownLength, knownScore], fullfile(filePath, fileName), "Delimiter", ",", "WriteMode", "append");
            tableData = app.TimeScoreTable.Data;
            
            fileID = fopen(fullfile(filePath, fileName), "a");
            fprintf(fileID, "%s,%s", "Times", "Scores");
            fclose(fileID);

            writetable(table(tableData), fullfile(filePath, fileName), "WriteVariableNames", false, "WriteMode", "append");
        end
        end

        % Button pushed function: LoadButton
        function load(app, event)
        [fileName, filePath] = uigetfile("*.csv", "Load Data");
        if fileName ~= 0
            fileID = fopen(fullfile(filePath, fileName), "r");
            fgetl(fileID);
            knownCombo = split(fgetl(fileID), ",");
            app.KnownTimeEditField.Value = str2double(knownCombo(1));
            app.KnownLengthEditField.Value = str2double(knownCombo(2));
            app.KnownScoreEditField.Value = str2double(knownCombo(3));

            fgetl(fileID);
            tableData = textscan(fileID, "%f%f", "Delimiter", ",");
            fclose(fileID);
            app.TimeScoreTable.Data = [tableData{1}, tableData{2}];
            
            updatePlot(app);
        end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {494, 494};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {230, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.M3AnalysisToolLabel = uilabel(app.GridLayout3);
            app.M3AnalysisToolLabel.Text = 'M3 Analysis Tool';
            app.KnownLengthEditFieldLabel = uilabel(app.GridLayout3);
            app.KnownLengthEditFieldLabel.Text = 'Known Length';
            app.KnownLengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KnownLengthEditField.ValueChangedFcn = createCallbackFcn(app, @updatedKnownLength, true);
            app.KnownTimeEditFieldLabel = uilabel(app.GridLayout3);
            app.KnownTimeEditFieldLabel.Text = 'Known Time';
            app.KnownTimeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KnownTimeEditField.ValueChangedFcn = createCallbackFcn(app, @updatedKnownTime, true);
            app.KnownScoreEditFieldLabel = uilabel(app.GridLayout3);
            app.KnownScoreEditFieldLabel.Text = 'Known Score';
            app.KnownScoreEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KnownScoreEditField.ValueChangedFcn = createCallbackFcn(app, @updatedKnownScore, true);
            app.TimeScoreTable = uitable(app.GridLayout3);
            app.TimeScoreTable.ColumnName = {'Time'; 'Score'};
            app.TimeScoreTable.RowName = {};
            app.TimeScoreTable.ColumnEditable = true;
            app.TimeScoreTable.CellEditCallback = createCallbackFcn(app, @updatedTable, true);
            app.AddRowButton = uibutton(app.GridLayout3, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @addRow, true);
            app.AddRowButton.Text = 'Add Row';
            app.ClearTableButton = uibutton(app.GridLayout3, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @clearTable, true);
            app.ClearTableButton.Text = 'Clear Table';
            app.TESTButton = uibutton(app.GridLayout3, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @testFunction, true);
            app.TESTButton.Text = 'TEST';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Aircraft Performance')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Length')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'JAD Logo.png';
            app.LengthLabel = uilabel(app.GridLayout2);
            app.LengthLabel.Text = 'Length';
            app.TimeLabel = uilabel(app.GridLayout2);
            app.TimeLabel.Text = 'Time';
            app.LengthText = uilabel(app.GridLayout2);
            app.LengthText.Text = 'N/A';
            app.TimeText = uilabel(app.GridLayout2);
            app.TimeText.Text = 'N/A';
            app.WarningMsg = uilabel(app.GridLayout2);
            app.WarningMsg.Text = 'Error: Not all values set';
            app.CalculateTargetsButton = uibutton(app.GridLayout2, 'push');
            app.CalculateTargetsButton.ButtonPushedFcn = createCallbackFcn(app, @calculateTargets, true);
            app.CalculateTargetsButton.Text = 'Calculate Targets';
            app.TargetPlacementLabel = uilabel(app.GridLayout2);
            app.TargetPlacementLabel.Text = 'Target Placement';
            app.TargetPlacementEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TargetPlacementEditField.RoundFractionalValues = 'on';
            app.TargetTimeEditFieldLabel = uilabel(app.GridLayout2);
            app.TargetTimeEditFieldLabel.Text = 'Target Time';
            app.TargetTimeEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TargetLengthEditFieldLabel = uilabel(app.GridLayout2);
            app.TargetLengthEditFieldLabel.Text = 'Target Length';
            app.TargetLengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.CalculatePlacementButton = uibutton(app.GridLayout2, 'push');
            app.CalculatePlacementButton.ButtonPushedFcn = createCallbackFcn(app, @calculatePlacement, true);
            app.CalculatePlacementButton.Text = 'Calculate Placement';
            app.PlacementLabel = uilabel(app.GridLayout2);
            app.PlacementLabel.Text = 'Placement';
            app.PlacementText = uilabel(app.GridLayout2);
            app.PlacementText.Text = 'N/A';
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @load, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = M3AnalysisTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end